classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        InputDataTab                  matlab.ui.container.Tab
        EditFieldLabel                matlab.ui.control.Label
        EditField                     matlab.ui.control.EditField
        SpottingTab                   matlab.ui.container.Tab
        AlgorithmDropDownLabel        matlab.ui.control.Label
        AlgorithmDropDown             matlab.ui.control.DropDown
        InputChannelEditFieldLabel    matlab.ui.control.Label
        InputChannelEditField         matlab.ui.control.EditField
        OutputSeedNameEditFieldLabel  matlab.ui.control.Label
        OutputSeedNameEditField       matlab.ui.control.EditField
        SegmentationTab               matlab.ui.container.Tab
        MeasurementsTab               matlab.ui.container.Tab
        TrackingTab                   matlab.ui.container.Tab
        PlotsTab                      matlab.ui.container.Tab
        Panel                         matlab.ui.container.Panel
    end


    properties (Access = public)
        img % currently displayed image
        %smooth_factor % for spotting
        spotting
    end

    methods (Access = public)
    
        function update_plot(app)
            height = cell2mat(app.UITable.Data(:,2));
            weight = cell2mat(app.UITable.Data(:,3));
            tf = cell2mat(app.UITable.Data(:,4));
            idx = find(tf);
            plot(app.UIAxes,height(idx),weight(idx),'o');
            app.UIAxes.XLim = [1.2 2.15];
            app.UIAxes.YLim = [45 155];
         end


         function init_spotting(app)
            app.AlgorithmDropDown.Items = {'spotA', 'spotB'};
            app.AlgorithmDropDown.Value = 'spotA';
            algo_name = app.AlgorithmDropDown.Value;
            eval(['definition_' algo_name]);
            draw(algo_name, params, app, @createCallbackFcn) % initialize dynamic GUI
            app.spotting.fields{1}.ValueChangedFcn(app, 'Update') % trigger once
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, eu)
            set(0,'DefaultFigureWindowStyle','docked')
            addpath(genpath('functions'));
            
            app.img = imread('r02c02f06p01-ch3sk74fk1fl1.tif');

            app.spotting = {};
            
            
            %             names = {'Ann'; 'Ramesh'; 'Al'; 'Kim'; 'Mary'; 'Sid'; 'Don'};
            %             height = num2cell([1.3; 1.4; 1.5; 1.6; 1.8; 1.9; 2.05;]);
            %             weight = num2cell([49;  60;  49; 103; 68; 138; 150]);
            %             show = num2cell(true(7,1));
            %             app.UITable.Data = [names height weight show];
            %             app.UITable.Position = [160 30 320 150];
            %
            %             % Plot initial data
            %             update_plot(app);
            
            app.TabGroup.SelectedTab = app.SpottingTab;
            init_spotting(app);


%             imshow('LFS00012.jpg','Parent',app.UIAxes_2);
%             im = imread('LFS00012.jpg');
%             f = figure(2); imshow3D(im);
%             imgs_to_gif(im);
%             
%             dcm_obj = datacursormode(f);
%             set(dcm_obj, 'Enable', 'on', 'UpdateFcn', {@datatip})
%             
%             [x,y,button] = ginput(1)


        end

        % Callback function
        function UITableCellEdit(app, event)
            update_plot(app);
        end

        % Value changed function: AlgorithmDropDown
        function AlgorithmDropDownValueChanged(app, event)
            algo_name = app.AlgorithmDropDown.Value;
            eval(['definition_' algo_name]);
            draw(algo_name, params, app, @createCallbackFcn) % initialize dynamic GUI
            app.spotting.fields{1}.ValueChangedFcn(app, 'Update') % trigger once
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InputDataTab = uitab(app.TabGroup);
            app.InputDataTab.Title = 'Input Data';
            app.EditFieldLabel = uilabel(app.InputDataTab);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.InputDataTab, 'text');
            app.SpottingTab = uitab(app.TabGroup);
            app.SpottingTab.Title = 'Spotting';
            app.AlgorithmDropDownLabel = uilabel(app.SpottingTab);
            app.AlgorithmDropDownLabel.Text = 'Algorithm';
            app.AlgorithmDropDown = uidropdown(app.SpottingTab);
            app.AlgorithmDropDown.Items = {};
            app.AlgorithmDropDown.ValueChangedFcn = createCallbackFcn(app, @AlgorithmDropDownValueChanged, true);
            app.AlgorithmDropDown.Value = {};
            app.InputChannelEditFieldLabel = uilabel(app.SpottingTab);
            app.InputChannelEditFieldLabel.Text = 'Input Channel';
            app.InputChannelEditField = uieditfield(app.SpottingTab, 'text');
            app.InputChannelEditField.Value = 'Nucleus';
            app.OutputSeedNameEditFieldLabel = uilabel(app.SpottingTab);
            app.OutputSeedNameEditFieldLabel.Text = 'Output Seed Name';
            app.OutputSeedNameEditField = uieditfield(app.SpottingTab, 'text');
            app.OutputSeedNameEditField.Value = 'Nucleus';
            app.SegmentationTab = uitab(app.TabGroup);
            app.SegmentationTab.Title = 'Segmentation';
            app.MeasurementsTab = uitab(app.TabGroup);
            app.MeasurementsTab.Title = 'Measurements';
            app.TrackingTab = uitab(app.TabGroup);
            app.TrackingTab.Title = 'Tracking';
            app.PlotsTab = uitab(app.TabGroup);
            app.PlotsTab.Title = 'Plots';
            app.Panel = uipanel(app.PlotsTab);
            app.Panel.Title = 'Panel';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end